classdef scaler_hypercube_comparison < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        hypercubeInfoLabel              matlab.ui.control.Label
        FatdepthlowerthresholdmmSlider  matlab.ui.control.Slider
        FatDepthUpperDisplay            matlab.ui.control.NumericEditField
        FatdepthupperthresholdmmSlider  matlab.ui.control.Slider
        FatdepthupperthresholdmmSliderLabel  matlab.ui.control.Label
        FatdepthlowerthresholdmmSliderLabel  matlab.ui.control.Label
        FatDepthLowerDisplay            matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        BlueSpinner                     matlab.ui.control.Spinner
        BlueSpinnerLabel                matlab.ui.control.Label
        GreenSpinner                    matlab.ui.control.Spinner
        GreenSpinnerLabel               matlab.ui.control.Label
        RedSpinner                      matlab.ui.control.Spinner
        RedSpinnerLabel                 matlab.ui.control.Label
        UIAxesFalse1                    matlab.ui.control.UIAxes
        UIAxesFalse2                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        hypercube1 = [];
        scalarAttribute1 = [];
        hypercube2 = [];
        scalarAttribute2 = [];
        pixelsV1 = []; 
        pixelsU1 = [];
        pixelsV2 = []; 
        pixelsU2 = [];
        numBands = [];
        scalarAttributeMax = [];
        scalarAttributeMin = [];
        scalarPlotMask1 = [];
        reflectanceMaskedList1 = [];
        scalarPlotMask2 = [];
        reflectanceMaskedList2 = [];
        yLimits = [];
        falseColorChannelIndices = [];
    end
    
    methods (Access = private)
        
        function plotreflectancestdscalarattribute(app)
            
            % Clear the existing plot
            cla(app.UIAxes);

            meanCurve = mean(app.reflectanceMaskedList1, 1);  
            stdDev = std(app.reflectanceMaskedList1, 0, 1);  

            % Plot the mean curve
            p1 = plot(app.UIAxes, meanCurve, 'b', 'DisplayName','Cube 1');
            hold(app.UIAxes, 'on');

            % Plot the shaded region (standard deviation bounds)
            x = 1:length(meanCurve);
            fill(app.UIAxes, [x fliplr(x)], [meanCurve + stdDev fliplr(meanCurve - stdDev)], 'b', 'FaceAlpha', 0.3, 'EdgeColor', 'none');

            meanCurve = mean(app.reflectanceMaskedList2, 1);  
            stdDev = std(app.reflectanceMaskedList2, 0, 1);  

            % Plot the mean curve
            p2 = plot(app.UIAxes, meanCurve, 'r', 'DisplayName','Cube 2');

            % Plot the shaded region (standard deviation bounds)
            x = 1:length(meanCurve);
            fill(app.UIAxes, [x fliplr(x)], [meanCurve + stdDev fliplr(meanCurve - stdDev)], 'r', 'FaceAlpha', 0.3, 'EdgeColor', 'none');

            ylabel(app.UIAxes, 'Reflectance');
            xlabel(app.UIAxes, 'Hyperspectral band number (change to wavelength)');
            grid(app.UIAxes, 'on');
            xlim(app.UIAxes, [0, app.numBands]);
            legend(app.UIAxes, [p1, p2]);
            
            if ~isempty(app.yLimits)
                app.UIAxes.YLim = app.yLimits;
            end

            hold(app.UIAxes, 'off');

        end

        function newscalarplotmask(app, minValue, maxValue)
            
            app.scalarPlotMask1 = (app.scalarAttribute1 >= minValue) & (app.scalarAttribute1 <= maxValue);
            app.scalarPlotMask2 = (app.scalarAttribute2 >= minValue) & (app.scalarAttribute2 <= maxValue);

%             app.updatebinaryimage();

            [rows, cols] = find(app.scalarPlotMask1);

            app.reflectanceMaskedList1 = zeros(length(rows), app.numBands);

            for b = 1:app.numBands
                curReflectanceList = impixel(app.hypercube1(:,:,b), cols, rows);
                app.reflectanceMaskedList1(:, b) = curReflectanceList(:,1);
            end

            [rows, cols] = find(app.scalarPlotMask2);

            app.reflectanceMaskedList2 = zeros(length(rows), app.numBands);

            for b = 1:app.numBands
                curReflectanceList = impixel(app.hypercube2(:,:,b), cols, rows);
                app.reflectanceMaskedList2(:, b) = curReflectanceList(:,1);
            end
        end

        function updateslider(app, upperValue, lowerValue)
            app.FatdepthlowerthresholdmmSlider.Value = lowerValue;
            app.FatdepthupperthresholdmmSlider.Value = upperValue;
        end

        function updatedisplay(app, upperValue, lowerValue)
            app.FatDepthLowerDisplay.Value = lowerValue;
            app.FatDepthUpperDisplay.Value = upperValue;
        end

        function updatebinaryimage(app)
            cla(app.UIAxesFalse1);
            imshow(app.scalarPlotMask1, 'Parent',app.UIAxesFalse1);
            axis(app.UIAxesFalse1, 'off');
            ylabel(app.UIAxesFalse1, '');
            xlabel(app.UIAxesFalse1, '');
            title(app.UIAxesFalse1, 'Binary fat depth threshold image')
        end

        function updatefalseimage(app)
            cla(app.UIAxesFalse1);
            cla(app.UIAxesFalse2);

            falseImg = zeros([app.pixelsV1, app.pixelsU1, 3]);

            for i = 1:3
                falseImg(:,:,i) = app.hypercube1(:,:,app.falseColorChannelIndices(i));
            end

            falseImg = rescale(falseImg);
            imshow(falseImg, 'Parent',app.UIAxesFalse1);

            
            falseImg = zeros([app.pixelsV2, app.pixelsU2, 3]);

            for i = 1:3
                falseImg(:,:,i) = app.hypercube2(:,:,app.falseColorChannelIndices(i));
            end

            falseImg = rescale(falseImg);
            imshow(falseImg, 'Parent',app.UIAxesFalse2);

            axis(app.UIAxesFalse1, 'off');
            ylabel(app.UIAxesFalse1, '');
            xlabel(app.UIAxesFalse1, '');
            title(app.UIAxesFalse1, 'False color image 1');

            axis(app.UIAxesFalse2, 'off');
            ylabel(app.UIAxesFalse2, '');
            xlabel(app.UIAxesFalse2, '');
            title(app.UIAxesFalse2, 'False color image 2');
        end

%         function hypercube2falseimage(app)
%             % Converts a hypercube to a false colour image in RGB.
%             % INPUTS:
%             %       cube - hypercube [u,v,bands]
%             %       rgbChannels - channelS from the hypercube that will be used as
%             %       RGB colour channels [1,3]
%             % OUTPUT:
%             %       falseImg - false color image [u,v,3]
%             % Author: Jasprabhjit Mehami, 13446277
% 
% 
%             falseImg = zeros([app.pixelsV, app.pixelsU, 3]);
% 
%             for i = 1:3
%                 falseImg(:,:,i) = app.hypercube(:,:,app.falseColorChannelIndices(i));
%             end
% 
%             minVal = min(falseImg, [], 'all');
%             maxVal = max(falseImg, [], 'all');
% 
%             falseImg = (falseImg - minVal)./(maxVal - minVal);
% 
%             % clip values between [0 1]
%             falseImg = min(falseImg, 1);
%             falseImg = max(falseImg, 0);
%         end
        
        function value = checkfalsecolorchannel(app, value)
            % make sure numeric and integer
            if ~isnumeric(value)
                value = 1;
            else
                value = round(value);
            end

                
            if value < 1
                value = 1;
            elseif value > app.numBands
                value =  app.numBands;
            end
        end
    end
% 
%     methods (Access = private, Static = true)
%         function falseImg = hypercube2falseimage(cube, rgbChannels)
%             % Converts a hypercube to a false colour image in RGB.
%             % INPUTS:
%             %       cube - hypercube [u,v,bands]
%             %       rgbChannels - channelS from the hypercube that will be used as
%             %       RGB colour channels [1,3]
%             % OUTPUT:
%             %       falseImg - false color image [u,v,3]
%             % Author: Jasprabhjit Mehami, 13446277
% 
% 
%             falseImg = zeros([size(cube, [1,2]), 3]);
% 
%             for i = 1:3
%                 falseImg(:,:,i) = cube(:,:,rgbChannels(i));
%             end
% 
%             minVal = min(falseImg, [], 'all');
%             maxVal = max(falseImg, [], 'all');
% 
%             falseImg = (falseImg - minVal)./(maxVal - minVal);
% 
%             % clip values between [0 1]
%             falseImg = min(falseImg, 1);
%             falseImg = max(falseImg, 0);
% 
%         end
% 
%     end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hypercube1, scalarAttribute1, hypercube2, scalarAttribute2)
            % Get the data to the input:
            app.hypercube1 = hypercube1;
            app.scalarAttribute1 = scalarAttribute1;
            app.hypercube2 = hypercube2;
            app.scalarAttribute2 = scalarAttribute2;
            
            [app.pixelsV1, app.pixelsU1, numBands1] = size(app.hypercube1);
            [app.pixelsV2, app.pixelsU2, numBands2] = size(app.hypercube2);

            if numBands1 ~= numBands2   
                error("Input hypercubes do not have the same number of spectral bands")
            end
            
            app.numBands = numBands1;

            app.hypercubeInfoLabel.Text = sprintf("(height x width x number bands)\n Hypercube 1: %i x %i x %i \n Hypercube 2: %i x %i x %i ", ...
                app.pixelsV1, app.pixelsU1, app.numBands, app.pixelsV2, app.pixelsU2, app.numBands);
            app.hypercubeInfoLabel.HorizontalAlignment = 'center';

            app.falseColorChannelIndices = floor(linspace(1, app.numBands, 3));
            app.RedSpinner.Value = app.falseColorChannelIndices(1);
            app.GreenSpinner.Value = app.falseColorChannelIndices(2);
            app.BlueSpinner.Value = app.falseColorChannelIndices(3);

            % Store the the app members:
            app.scalarAttributeMax = max([max(app.scalarAttribute1, [], "all"), max(app.scalarAttribute2, [], "all")], [], 'all');
            app.scalarAttributeMin = min([min(app.scalarAttribute1, [], "all"), min(app.scalarAttribute2, [], "all")], [], 'all');

            % Set up the slider's Min Max Value:
            app.FatdepthlowerthresholdmmSlider.Limits = [app.scalarAttributeMin, app.scalarAttributeMax];
            app.FatdepthupperthresholdmmSlider.Limits = [app.scalarAttributeMin, app.scalarAttributeMax];

            % Set up the initial values
            app.FatdepthlowerthresholdmmSlider.Value = app.scalarAttributeMin;
            app.FatdepthupperthresholdmmSlider.Value = app.scalarAttributeMax;

            app.FatDepthLowerDisplay.Value = app.scalarAttributeMin;
            app.FatDepthUpperDisplay.Value = app.scalarAttributeMax;
            
            app.FatdepthlowerthresholdmmSlider.MajorTicks = [app.scalarAttributeMin, app.scalarAttributeMax];
            app.FatdepthupperthresholdmmSlider.MajorTicks = [app.scalarAttributeMin, app.scalarAttributeMax];

            upperValue = app.scalarAttributeMax;
            lowerValue = app.scalarAttributeMin;

            app.updateslider(upperValue, lowerValue);
            app.updatedisplay(upperValue, lowerValue);

            app.newscalarplotmask(lowerValue, upperValue);
            app.plotreflectancestdscalarattribute();
            

            app.updatefalseimage();

            %get starting y limits
            app.yLimits = app.UIAxes.YLim;

        end

        % Value changed function: FatdepthupperthresholdmmSlider
        function FatdepthupperthresholdmmSliderValueChanged(app, event)
            upperValue = app.FatdepthupperthresholdmmSlider.Value;
            lowerValue = app.FatdepthlowerthresholdmmSlider.Value;
            

            % Ensure the lower value is always <= upper value
            if lowerValue > upperValue
                lowerValue = upperValue - 0.1;
            end

            app.updateslider(upperValue, lowerValue);
            app.updatedisplay(upperValue, lowerValue);

            newscalarplotmask(app, lowerValue, upperValue);
            plotreflectancestdscalarattribute(app);
        end

        % Value changed function: FatdepthlowerthresholdmmSlider
        function FatdepthlowerthresholdmmSliderValueChanged(app, event)
            upperValue = app.FatdepthupperthresholdmmSlider.Value;
            lowerValue = app.FatdepthlowerthresholdmmSlider.Value;

            % Ensure the lower value is always <= upper value
            if lowerValue > upperValue
                upperValue = lowerValue + 0.1; 
            end

            app.updateslider(upperValue, lowerValue);
            app.updatedisplay(upperValue, lowerValue);

            newscalarplotmask(app, lowerValue, upperValue);
            plotreflectancestdscalarattribute(app);
        end

        % Value changed function: FatDepthLowerDisplay
        function FatDepthLowerDisplayValueChanged(app, event)
            lowerValue = app.FatDepthLowerDisplay.Value;
            upperValue = app.FatDepthUpperDisplay.Value;
            
            % Ensure the lower value is always <= upper value
            if lowerValue > upperValue
                lowerValue = upperValue;
            end

            if lowerValue < app.scalarAttributeMin
                lowerValue = app.scalarAttributeMin;
            end

            app.updateslider(upperValue, lowerValue);
            app.updatedisplay(upperValue, lowerValue);

            newscalarplotmask(app, lowerValue, upperValue);
            plotreflectancestdscalarattribute(app);
        end

        % Value changed function: FatDepthUpperDisplay
        function FatDepthUpperDisplayValueChanged(app, event)
            lowerValue = app.FatDepthLowerDisplay.Value;
            upperValue = app.FatDepthUpperDisplay.Value;

            % Ensure the lower value is always <= upper value
            if lowerValue > upperValue
                lowerValue = upperValue;
            end

            if upperValue > app.scalarAttributeMax
                upperValue = app.scalarAttributeMax;
            end

            app.updateslider(upperValue, lowerValue);
            app.updatedisplay(upperValue, lowerValue);

            newscalarplotmask(app, lowerValue, upperValue);
            plotreflectancestdscalarattribute(app);
        end

        % Value changed function: RedSpinner
        function RedSpinnerValueChanged(app, event)
            value = app.RedSpinner.Value;
            value = app.checkfalsecolorchannel(value);
            app.RedSpinner.Value = value;
            app.falseColorChannelIndices(1) = value;
            app.updatefalseimage();
        end

        % Value changed function: GreenSpinner
        function GreenSpinnerValueChanged(app, event)
            value = app.GreenSpinner.Value;
            value = app.checkfalsecolorchannel(value);
            app.GreenSpinner.Value = value;
            app.falseColorChannelIndices(2) = value;
            app.updatefalseimage();
        end

        % Value changed function: BlueSpinner
        function BlueSpinnerValueChanged(app, event)
            value = app.BlueSpinner.Value;
            value = app.checkfalsecolorchannel(value);
            app.BlueSpinner.Value = value;
            app.falseColorChannelIndices(3) = value;
            app.updatefalseimage();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {691, 691};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {745, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Reflectance with STD for upper and lower fat depth')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FatDepthLowerDisplay = uieditfield(app.GridLayout2, 'numeric');
            app.FatDepthLowerDisplay.ValueChangedFcn = createCallbackFcn(app, @FatDepthLowerDisplayValueChanged, true);
            app.FatdepthlowerthresholdmmSliderLabel = uilabel(app.GridLayout2);
            app.FatdepthlowerthresholdmmSliderLabel.Text = 'Fat depth lower threshold (mm)';
            app.FatdepthupperthresholdmmSliderLabel = uilabel(app.GridLayout2);
            app.FatdepthupperthresholdmmSliderLabel.Text = 'Fat depth upper threshold (mm)';
            app.FatdepthupperthresholdmmSlider = uislider(app.GridLayout2);
            app.FatdepthupperthresholdmmSlider.ValueChangedFcn = createCallbackFcn(app, @FatdepthupperthresholdmmSliderValueChanged, true);
            app.FatDepthUpperDisplay = uieditfield(app.GridLayout2, 'numeric');
            app.FatDepthUpperDisplay.ValueChangedFcn = createCallbackFcn(app, @FatDepthUpperDisplayValueChanged, true);
            app.FatdepthlowerthresholdmmSlider = uislider(app.GridLayout2);
            app.FatdepthlowerthresholdmmSlider.ValueChangedFcn = createCallbackFcn(app, @FatdepthlowerthresholdmmSliderValueChanged, true);
            app.hypercubeInfoLabel = uilabel(app.GridLayout2);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxesFalse2 = uiaxes(app.GridLayout3);
            title(app.UIAxesFalse2, 'Title')
            xlabel(app.UIAxesFalse2, 'X')
            ylabel(app.UIAxesFalse2, 'Y')
            zlabel(app.UIAxesFalse2, 'Z')
            app.UIAxesFalse1 = uiaxes(app.GridLayout3);
            title(app.UIAxesFalse1, 'Title')
            xlabel(app.UIAxesFalse1, 'X')
            ylabel(app.UIAxesFalse1, 'Y')
            zlabel(app.UIAxesFalse1, 'Z')
            app.RedSpinnerLabel = uilabel(app.GridLayout3);
            app.RedSpinnerLabel.Text = 'Red';
            app.RedSpinner = uispinner(app.GridLayout3);
            app.RedSpinner.ValueChangedFcn = createCallbackFcn(app, @RedSpinnerValueChanged, true);
            app.GreenSpinnerLabel = uilabel(app.GridLayout3);
            app.GreenSpinnerLabel.Text = 'Green';
            app.GreenSpinner = uispinner(app.GridLayout3);
            app.GreenSpinner.ValueChangedFcn = createCallbackFcn(app, @GreenSpinnerValueChanged, true);
            app.BlueSpinnerLabel = uilabel(app.GridLayout3);
            app.BlueSpinnerLabel.Text = 'Blue';
            app.BlueSpinner = uispinner(app.GridLayout3);
            app.BlueSpinner.ValueChangedFcn = createCallbackFcn(app, @BlueSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scaler_hypercube_comparison(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end